classdef ExperimentoProtocolo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image_2                       matlab.ui.control.Image
        RafaelVillalbaLabel_2         matlab.ui.control.Label
        Experimentov10Label_2         matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        InicioFinExperimentosTab      matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        RutaGrabacionEmpatica         matlab.ui.control.EditField
        RutaGrabacionCaneSense        matlab.ui.control.EditField
        RutaGrabacionEmpaticaLabel    matlab.ui.control.Label
        RutaGrabacionCaneSenseLabel   matlab.ui.control.Label
        TiempoOverlap                 matlab.ui.control.NumericEditField
        TiempoVentana                 matlab.ui.control.NumericEditField
        TiempoOverlapLabel            matlab.ui.control.Label
        TiempoVentanaLabel            matlab.ui.control.Label
        GuardarFeaturesButton         matlab.ui.control.Button
        ExaminarFeaturesButton        matlab.ui.control.Button
        RutaGuardarFeatures           matlab.ui.control.EditField
        RutaGuardarFeaturesLabel      matlab.ui.control.Label
        NumParticipante               matlab.ui.control.Spinner
        ParticipanteNLabel            matlab.ui.control.Label
        FechaExperimento              matlab.ui.control.DatePicker
        FechadelexperimentoLabel      matlab.ui.control.Label
        InicioFindelexperimentoLabel  matlab.ui.control.Label
        FinCalculo                    matlab.ui.control.EditField
        InicioCalculo                 matlab.ui.control.EditField
        FinRelax2                     matlab.ui.control.EditField
        InicioRelax2                  matlab.ui.control.EditField
        FinMusica                     matlab.ui.control.EditField
        InicioMusica                  matlab.ui.control.EditField
        FinRelax1                     matlab.ui.control.EditField
        InicioRelax1                  matlab.ui.control.EditField
        ClculoAritmticoLabel_2        matlab.ui.control.Label
        Relax2Label                   matlab.ui.control.Label
        EventosMsicaLabel             matlab.ui.control.Label
        Relax1Label                   matlab.ui.control.Label
        FinLabel                      matlab.ui.control.Label
        InicioLabel                   matlab.ui.control.Label
        EvaluacinCuestionarioAETab    matlab.ui.container.Tab
        GridLayout8                   matlab.ui.container.GridLayout
        Label_2                       matlab.ui.control.Label
        Fieldcalculo                  matlab.ui.control.NumericEditField
        Fieldrelax2                   matlab.ui.control.NumericEditField
        Fieldmusica                   matlab.ui.control.NumericEditField
        Fieldrelax1                   matlab.ui.control.NumericEditField
        ClculoLabel                   matlab.ui.control.Label
        Relax2Label_2                 matlab.ui.control.Label
        MsicaLabel                    matlab.ui.control.Label
        STAIAEScoreLabel              matlab.ui.control.Label
        ExperimentoLabel              matlab.ui.control.Label
        Relax1Label_2                 matlab.ui.control.Label
        NumParticipante_2             matlab.ui.control.Spinner
        CalcularAEButton              matlab.ui.control.Button
        GuardarAEScore                matlab.ui.control.Button
        ParticipanteNLabel_2          matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        Relax1Tab                     matlab.ui.container.Tab
        GridLayout2_4                 matlab.ui.container.GridLayout
        Item20_relax1                 matlab.ui.control.Spinner
        Item10_relax1                 matlab.ui.control.Spinner
        Item19_relax1                 matlab.ui.control.Spinner
        Item9_relax1                  matlab.ui.control.Spinner
        Item18_relax1                 matlab.ui.control.Spinner
        Item8_relax1                  matlab.ui.control.Spinner
        Item17_relax1                 matlab.ui.control.Spinner
        Item7_relax1                  matlab.ui.control.Spinner
        Item16_relax1                 matlab.ui.control.Spinner
        Item6_relax1                  matlab.ui.control.Spinner
        Label20_relax1                matlab.ui.control.Label
        Label19_relax1                matlab.ui.control.Label
        Label18_relax1                matlab.ui.control.Label
        Label10_relax1                matlab.ui.control.Label
        Label9_relax1                 matlab.ui.control.Label
        Label7_relax1                 matlab.ui.control.Label
        Label17_relax1                matlab.ui.control.Label
        Label16_relax1                matlab.ui.control.Label
        Label8_relax1                 matlab.ui.control.Label
        Label6_relax1                 matlab.ui.control.Label
        Label5_relax1                 matlab.ui.control.Label
        Label4_relax1                 matlab.ui.control.Label
        Label3_relax1                 matlab.ui.control.Label
        Label2_relax1                 matlab.ui.control.Label
        Label15_relax1                matlab.ui.control.Label
        Label14_relax1                matlab.ui.control.Label
        Label13_relax1                matlab.ui.control.Label
        Label12_relax1                matlab.ui.control.Label
        Label11_relax1                matlab.ui.control.Label
        Item15_relax1                 matlab.ui.control.Spinner
        Item13_relax1                 matlab.ui.control.Spinner
        Item4_relax1                  matlab.ui.control.Spinner
        Item3_relax1                  matlab.ui.control.Spinner
        Item12_relax1                 matlab.ui.control.Spinner
        Item5_relax1                  matlab.ui.control.Spinner
        Item2_relax1                  matlab.ui.control.Spinner
        Item11_relax1                 matlab.ui.control.Spinner
        Item14_relax1                 matlab.ui.control.Spinner
        Item1_relax1                  matlab.ui.control.Spinner
        Label1_relax1                 matlab.ui.control.Label
        MsicaTab                      matlab.ui.container.Tab
        GridLayout2_6                 matlab.ui.container.GridLayout
        Item20_musica                 matlab.ui.control.Spinner
        Item10_musica                 matlab.ui.control.Spinner
        Item19_musica                 matlab.ui.control.Spinner
        Item9_musica                  matlab.ui.control.Spinner
        Item18_musica                 matlab.ui.control.Spinner
        Item8_musica                  matlab.ui.control.Spinner
        Item17_musica                 matlab.ui.control.Spinner
        Item7_musica                  matlab.ui.control.Spinner
        Item16_musica                 matlab.ui.control.Spinner
        Item6_musica                  matlab.ui.control.Spinner
        Label20_3                     matlab.ui.control.Label
        Label19_3                     matlab.ui.control.Label
        Label18_3                     matlab.ui.control.Label
        Label10_3                     matlab.ui.control.Label
        Label9_3                      matlab.ui.control.Label
        Label7_3                      matlab.ui.control.Label
        Label17_3                     matlab.ui.control.Label
        Label16_3                     matlab.ui.control.Label
        Label8_3                      matlab.ui.control.Label
        Label6_3                      matlab.ui.control.Label
        Label5_3                      matlab.ui.control.Label
        Label4_3                      matlab.ui.control.Label
        Label3_3                      matlab.ui.control.Label
        Label2_3                      matlab.ui.control.Label
        Label15_3                     matlab.ui.control.Label
        Label14_3                     matlab.ui.control.Label
        Label13_3                     matlab.ui.control.Label
        Label12_3                     matlab.ui.control.Label
        Label11_3                     matlab.ui.control.Label
        Item15_musica                 matlab.ui.control.Spinner
        Item13_musica                 matlab.ui.control.Spinner
        Item4_musica                  matlab.ui.control.Spinner
        Item3_musica                  matlab.ui.control.Spinner
        Item12_musica                 matlab.ui.control.Spinner
        Item5_musica                  matlab.ui.control.Spinner
        Item2_musica                  matlab.ui.control.Spinner
        Item11_musica                 matlab.ui.control.Spinner
        Item14_musica                 matlab.ui.control.Spinner
        Item1_musica                  matlab.ui.control.Spinner
        Label1_3                      matlab.ui.control.Label
        Relax2Tab                     matlab.ui.container.Tab
        GridLayout2_7                 matlab.ui.container.GridLayout
        Item20_relax2                 matlab.ui.control.Spinner
        Item10_relax2                 matlab.ui.control.Spinner
        Item19_relax2                 matlab.ui.control.Spinner
        Item9_relax2                  matlab.ui.control.Spinner
        Item18_relax2                 matlab.ui.control.Spinner
        Item8_relax2                  matlab.ui.control.Spinner
        Item17_relax2                 matlab.ui.control.Spinner
        Item7_relax2                  matlab.ui.control.Spinner
        Item16_relax2                 matlab.ui.control.Spinner
        Item6_relax2                  matlab.ui.control.Spinner
        Label20_4                     matlab.ui.control.Label
        Label19_4                     matlab.ui.control.Label
        Label18_4                     matlab.ui.control.Label
        Label10_4                     matlab.ui.control.Label
        Label9_4                      matlab.ui.control.Label
        Label7_4                      matlab.ui.control.Label
        Label17_4                     matlab.ui.control.Label
        Label16_relax2                matlab.ui.control.Label
        Label8_relax2                 matlab.ui.control.Label
        Label6_relax2                 matlab.ui.control.Label
        Label5_relax2                 matlab.ui.control.Label
        Label4_relax2                 matlab.ui.control.Label
        Label3_relax2                 matlab.ui.control.Label
        Label2_relax2                 matlab.ui.control.Label
        Label15_relax2                matlab.ui.control.Label
        Label14_relax2                matlab.ui.control.Label
        Label13_relax2                matlab.ui.control.Label
        Label12_relax2                matlab.ui.control.Label
        Label11_relax2                matlab.ui.control.Label
        Item15_relax2                 matlab.ui.control.Spinner
        Item13_relax2                 matlab.ui.control.Spinner
        Item4_relax2                  matlab.ui.control.Spinner
        Item3_relax2                  matlab.ui.control.Spinner
        Item12_relax2                 matlab.ui.control.Spinner
        Item5_relax2                  matlab.ui.control.Spinner
        Item2_relax2                  matlab.ui.control.Spinner
        Item11_relax2                 matlab.ui.control.Spinner
        Item14_relax2                 matlab.ui.control.Spinner
        Item1_relax2                  matlab.ui.control.Spinner
        Label1_relax2                 matlab.ui.control.Label
        ClculoTab_2                   matlab.ui.container.Tab
        GridLayout2_5                 matlab.ui.container.GridLayout
        Item20_calculo                matlab.ui.control.Spinner
        Item10_calculo                matlab.ui.control.Spinner
        Item19_calculo                matlab.ui.control.Spinner
        Item9_calculo                 matlab.ui.control.Spinner
        Item18_calculo                matlab.ui.control.Spinner
        Item8_calculo                 matlab.ui.control.Spinner
        Item17_calculo                matlab.ui.control.Spinner
        Item7_calculo                 matlab.ui.control.Spinner
        Item16_calculo                matlab.ui.control.Spinner
        Item6_calculo                 matlab.ui.control.Spinner
        Label20_2                     matlab.ui.control.Label
        Label19_2                     matlab.ui.control.Label
        Label18_2                     matlab.ui.control.Label
        Label10_2                     matlab.ui.control.Label
        Label9_2                      matlab.ui.control.Label
        Label7_2                      matlab.ui.control.Label
        Label17_2                     matlab.ui.control.Label
        Label16_2                     matlab.ui.control.Label
        Label8_2                      matlab.ui.control.Label
        Label6_2                      matlab.ui.control.Label
        Label5_2                      matlab.ui.control.Label
        Label4_2                      matlab.ui.control.Label
        Label3_2                      matlab.ui.control.Label
        Label2_2                      matlab.ui.control.Label
        Label15_2                     matlab.ui.control.Label
        Label14_2                     matlab.ui.control.Label
        Label13_2                     matlab.ui.control.Label
        Label12_2                     matlab.ui.control.Label
        Label11_2                     matlab.ui.control.Label
        Item15_calculo                matlab.ui.control.Spinner
        Item13_calculo                matlab.ui.control.Spinner
        Item4_calculo                 matlab.ui.control.Spinner
        Item3_calculo                 matlab.ui.control.Spinner
        Item12_calculo                matlab.ui.control.Spinner
        Item5_calculo                 matlab.ui.control.Spinner
        Item2_calculo                 matlab.ui.control.Spinner
        Item11_calculo                matlab.ui.control.Spinner
        Item14_calculo                matlab.ui.control.Spinner
        Item1_calculo                 matlab.ui.control.Spinner
        Label1_2                      matlab.ui.control.Label
        EvaluacinCuestionarioARTab    matlab.ui.container.Tab
        GridLayout8_2                 matlab.ui.container.GridLayout
        Label_3                       matlab.ui.control.Label
        FieldAR                       matlab.ui.control.NumericEditField
        STAIARScoreLabel              matlab.ui.control.Label
        NumParticipante_3             matlab.ui.control.Spinner
        CalcularARButton              matlab.ui.control.Button
        GuardarARScore                matlab.ui.control.Button
        ParticipanteNLabel_3          matlab.ui.control.Label
        TabGroup2_2                   matlab.ui.container.TabGroup
        ARTab                         matlab.ui.container.Tab
        GridLayout2_11                matlab.ui.container.GridLayout
        Item20_calculo_2              matlab.ui.control.Spinner
        Item10_calculo_2              matlab.ui.control.Spinner
        Item19_calculo_2              matlab.ui.control.Spinner
        Item9_calculo_2               matlab.ui.control.Spinner
        Item18_calculo_2              matlab.ui.control.Spinner
        Item8_calculo_2               matlab.ui.control.Spinner
        Item17_calculo_2              matlab.ui.control.Spinner
        Item7_calculo_2               matlab.ui.control.Spinner
        Item16_calculo_2              matlab.ui.control.Spinner
        Item6_calculo_2               matlab.ui.control.Spinner
        Label20_7                     matlab.ui.control.Label
        Label19_7                     matlab.ui.control.Label
        Label18_7                     matlab.ui.control.Label
        Label10_7                     matlab.ui.control.Label
        Label9_7                      matlab.ui.control.Label
        Label7_7                      matlab.ui.control.Label
        Label17_7                     matlab.ui.control.Label
        Label16_5                     matlab.ui.control.Label
        Label8_5                      matlab.ui.control.Label
        Label6_5                      matlab.ui.control.Label
        Label5_5                      matlab.ui.control.Label
        Label4_5                      matlab.ui.control.Label
        Label3_5                      matlab.ui.control.Label
        Label2_5                      matlab.ui.control.Label
        Label15_5                     matlab.ui.control.Label
        Label14_5                     matlab.ui.control.Label
        Label13_5                     matlab.ui.control.Label
        Label12_5                     matlab.ui.control.Label
        Label11_5                     matlab.ui.control.Label
        Item15_calculo_2              matlab.ui.control.Spinner
        Item13_calculo_2              matlab.ui.control.Spinner
        Item4_calculo_2               matlab.ui.control.Spinner
        Item3_calculo_2               matlab.ui.control.Spinner
        Item12_calculo_2              matlab.ui.control.Spinner
        Item5_calculo_2               matlab.ui.control.Spinner
        Item2_calculo_2               matlab.ui.control.Spinner
        Item11_calculo_2              matlab.ui.control.Spinner
        Item14_calculo_2              matlab.ui.control.Spinner
        Item1_calculo_2               matlab.ui.control.Spinner
        Label1_5                      matlab.ui.control.Label
        PreguntasGeneralesTab         matlab.ui.container.Tab
        GridLayout12                  matlab.ui.container.GridLayout
        EnfermoNo                     matlab.ui.control.CheckBox
        EnfermoSi                     matlab.ui.control.CheckBox
        TesientesconmalcuerpooenfermoLabel  matlab.ui.control.Label
        HasfumadoenlaltimahoraantesdelexpLabel  matlab.ui.control.Label
        EresfumadorLabel              matlab.ui.control.Label
        HashechodeportedealtaintensidadhoyLabel  matlab.ui.control.Label
        Hasbebidocafenlasltimas6horasLabel  matlab.ui.control.Label
        HaFumadoNo                    matlab.ui.control.CheckBox
        HaFumadoSi                    matlab.ui.control.CheckBox
        FumaNo                        matlab.ui.control.CheckBox
        FumaSi                        matlab.ui.control.CheckBox
        DeporteNo                     matlab.ui.control.CheckBox
        DeporteSi                     matlab.ui.control.CheckBox
        CafeNo                        matlab.ui.control.CheckBox
        CafeSi                        matlab.ui.control.CheckBox
        Varon                         matlab.ui.control.CheckBox
        SexoLabel                     matlab.ui.control.Label
        Mujer                         matlab.ui.control.CheckBox
        EdadParticipante              matlab.ui.control.Spinner
        EdadLabel                     matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        RafaelVillalbaLabel           matlab.ui.control.Label
        Experimentov10Label           matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fechaHoy = datetime('now', 'Format', 'dd-MM-yyyy');
            app.FechaExperimento.Value = fechaHoy;
        end

        % Button pushed function: GuardarFeaturesButton
        function GuardarFeaturesButtonPushed(app, event)
            % Campos a validar
            fields = {'InicioRelax1', 'FinRelax1', 'InicioMusica', 'FinMusica', ...
              'InicioRelax2', 'FinRelax2', 'InicioCalculo', 'FinCalculo'};
            % Fecha del experimento
            experimentDate = app.FechaExperimento.Value;
            
            if isempty(app.RutaGuardarFeatures.Value)
                    uialert(app.UIFigure, ['Establece una ruta para guardar la tabla de características extraídas.'], 'Error', 'Icon', 'error');
                    return;
            end

            if isempty(app.RutaGrabacionCaneSense.Value)
                    uialert(app.UIFigure, ['Añade la ruta donde se encuentra la grabación CaneSense.'], 'Error', 'Icon', 'error');
                    return;
            end

            if isempty(app.RutaGrabacionEmpatica.Value)
                    uialert(app.UIFigure, ['Añade la ruta donde se encuentra la grabación de Empatica.'], 'Error', 'Icon', 'error');
                    return;
            end

            if isempty(app.RutaGuardarFeatures.Value)
                    uialert(app.UIFigure, ['Establece una ruta para guardar la tabla de características extraídas.'], 'Error', 'Icon', 'error');
                    return;
            end

            % Validación de que los campos no están vacíos y tienen el formato correcto
            for i = 1:length(fields)
                field = fields{i};
                inputValue = app.(field).Value;
                
                % 1. Verificar que el campo tiene algún valor
                if isempty(inputValue)
                    uialert(app.UIFigure, ['El campo ' field ' no puede estar vacío.'], 'Error', 'Icon', 'error');
                    return;
                end
                
                % 2. Convertirlo a Datetime
                try
                    % Intentamos convertir el texto en un datetime con el formato específico
                    timeValue = datetime(inputValue, 'InputFormat', 'HH:mm:ss.SS');

                    datetimeValue = datetime(experimentDate.Year, experimentDate.Month, experimentDate.Day, ...
                        hour(timeValue), minute(timeValue), second(timeValue), ...
                        'Format', 'dd-MM-yyyy HH:mm:ss.SS');

                    eval([field ' = datetimeValue;']);

                catch
                    uialert(app.UIFigure, ['El formato del campo ' field ' es incorrecto. El formato debe ser HH:mm:ss.SS.'], 'Error', 'Icon', 'error');
                    return;
                end
            end

            try
                % Comparar las fechas y verificar la jerarquía de las variables temporales
                if InicioRelax1 >= FinRelax1
                    uialert(app.UIFigure, 'El valor de InicioRelax1 debe ser anterior a FinRelax1.', 'Error', 'Icon', 'error');
                    return;
                elseif InicioMusica >= FinMusica
                    uialert(app.UIFigure, 'El valor de InicioMusica debe ser anterior a FinMusica.', 'Error', 'Icon', 'error');
                    return;
                elseif InicioRelax2 >= FinRelax2
                    uialert(app.UIFigure, 'El valor de InicioRelax2 debe ser anterior a FinRelax2.', 'Error', 'Icon', 'error');
                    return;
                elseif InicioCalculo >= FinCalculo
                    uialert(app.UIFigure, 'El valor de InicioCalculo debe ser anterior a FinCalculo.', 'Error', 'Icon', 'error');
                    return;
                end
            catch
                uialert(app.UIFigure, 'Hubo un error al comparar los tiempos. Asegúrese de que todos los valores sean válidos.', 'Error', 'Icon', 'error');
                return;
            end

            if isempty(app.TiempoVentana.Value) || isempty(app.TiempoOverlap.Value)
                    uialert(app.UIFigure, ['Establece un tiempo de ventanas correcto.'], 'Error', 'Icon', 'error');
                    return;
            end

            if (app.TiempoVentana.Value <= 0) || isempty(app.TiempoOverlap.Value < 0)
                    uialert(app.UIFigure, ['Los tiempos de ventana deben ser positivos.'], 'Error', 'Icon', 'error');
                    return;
            end

            if (app.TiempoVentana.Value <= app.TiempoOverlap.Value)
                    uialert(app.UIFigure, ['El tiempo de ventana debe ser mayor que el tiempo de solape'], 'Error', 'Icon', 'error');
                    return;
            end

            try
                % Obtenemos el número de participante
                numParticipante = num2str(app.NumParticipante.Value);
                protocolTime_datetime_file = strcat('S',numParticipante,'_protocolTime_datetime');

                % Construimos el vector de tiempos
                protocolTime_datetime = [InicioRelax1, FinRelax1;
                InicioMusica, FinMusica;
                InicioRelax2, FinRelax2;
                InicioCalculo, FinCalculo];

                eval([protocolTime_datetime_file ' = protocolTime_datetime']);
            catch
                uialert(app.UIFigure, 'Hubo un error al generar el vector de tiempos de los experimentos.', 'Error', 'Icon', 'error');
                return;
            end
            try
                % Tratamos de generar las tablas con las características
                % extraídas del experimento.
                % Para ello añadimos al path la función
                appDir = fileparts(mfilename('fullpath'));
                addpath(fullfile(appDir, '..'));

                % Obtenemos el tiempo de ventana y tiempo de solape
                winTime = app.TiempoVentana.Value;
                winOverlapTime = app.TiempoOverlap.Value;

                % Obtenemos las grabaciones de la Empatica
                ruta_empatica_data = app.RutaGrabacionEmpatica.Value;
                ruta_empatica_data = strrep(ruta_empatica_data,'''', '');
                ruta_empatica_data = strrep(ruta_empatica_data,'"', '');
                empaticaData = load(ruta_empatica_data, 'dat');

                % Obtenemos las grabaciones de CaneSense
                ruta_cane_data = app.RutaGrabacionCaneSense.Value;
                ruta_cane_data = strrep(ruta_cane_data,'''', '');
                ruta_cane_data = strrep(ruta_cane_data,'"', '');
                caneData = load(ruta_cane_data);
                
                % Verificamos si existen datos en el inicio/fin de los
                % experimentos para las señales Empatica y CaneSense
                InicioExperimento = InicioRelax1;
                FinExperimento = InicioRelax2;
                vectorTiemposCane = caneData.caneData.TimeStampDate;
                vectorTiemposEmpaticaPPG = empaticaData.dat.BVP{1}.local_time;
                vectorTiemposEmpaticaEDA = empaticaData.dat.EDA{1}.local_time;
                % Truncar todos los tiempos a resolución de segundos
                InicioExperimento = dateshift(InicioExperimento, 'start', 'second');
                FinExperimento = dateshift(FinExperimento, 'start', 'second');
                vectorTiemposCane = dateshift(vectorTiemposCane, 'start', 'second');
                vectorTiemposEmpaticaPPG = dateshift(vectorTiemposEmpaticaPPG, 'start', 'second');
                vectorTiemposEmpaticaEDA = dateshift(vectorTiemposEmpaticaEDA, 'start', 'second');
                % Eliminamos la Zona Horaria para la comparación
                InicioExperimento.TimeZone = '';
                FinExperimento.TimeZone = '';
                vectorTiemposCane.TimeZone = '';
                vectorTiemposEmpaticaPPG.TimeZone = '';
                vectorTiemposEmpaticaEDA.TimeZone = '';                
                % Verificación
                existeInicio =  any(InicioExperimento == vectorTiemposCane) && any(InicioExperimento == vectorTiemposEmpaticaPPG) ...
                                && any(InicioExperimento == vectorTiemposEmpaticaEDA);

                existeFin =  any(FinExperimento == vectorTiemposCane) && any(FinExperimento == vectorTiemposEmpaticaPPG) ...
                                && any(FinExperimento == vectorTiemposEmpaticaEDA);

               if (~existeInicio || ~existeFin)
                    msg = sprintf(['Uno o varios datos de las señales no se encuentran en la región de tiempo especificada en el protocolo.\n\n' ...
                                   'Verifica los tiempos y fecha del experimento.']);
                    uialert(app.UIFigure, msg, 'Error', 'Icon', 'error');
                    return
               end
               
                % Obtenemos las señales diferentes señales (PPG, EDA) de
                % cada experimento:
                [caneDataExperiment, empaticaDataExperiment] = getExperimentalSignal(empaticaData.dat, caneData.caneData, protocolTime_datetime);

                % Re-muestreo de las señales para que estén sincronizadas
                % (misma frecuencia de muestreo Fs)
                [empaticaDataExperiment_sync,caneDataExperiment_sync] = syncDataSampleRate(empaticaDataExperiment,caneDataExperiment);
    
                % Extracción de las características
                [empaticaFeatures, caneFeatures] = calculateFeatures(empaticaDataExperiment_sync, caneDataExperiment_sync, app.NumParticipante.Value, winTime, winOverlapTime);

            catch ME  
                errorMsg = sprintf('Hubo un error al realizar la extracción de características:\n\n%s\n\nEn: %s (línea %d)', ...
                                   ME.message, ...
                                   ME.stack(1).name, ...
                                   ME.stack(1).line);
                uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
                return;
            end

            try
                % Crear carpeta padre con nombre basado en el participante
                rutaGuardar = app.RutaGuardarFeatures.Value;
                carpetaPadre = fullfile(rutaGuardar, strcat('S', numParticipante, '_files'));
                if ~exist(carpetaPadre, 'dir')
                    mkdir(carpetaPadre);
                end

                % Crear subcarpetas 'signal' y 'features' dentro de la carpeta padre
                carpetaSignal = fullfile(carpetaPadre, 'signal');
                carpetaFeatures = fullfile(carpetaPadre, 'features');
                if ~exist(carpetaSignal, 'dir')
                    mkdir(carpetaSignal);
                end
                if ~exist(carpetaFeatures, 'dir')
                    mkdir(carpetaFeatures);
                end

                % .mat del archivo de tiempos:
                protocolTime_datetime_file_path = fullfile(carpetaPadre, protocolTime_datetime_file);

                % .mat de las señales sincronizadas:
                empaticaDataExperiment_sync_file = strcat('S',numParticipante,'_empaticaDataExperiment');
                eval([empaticaDataExperiment_sync_file ' = empaticaDataExperiment_sync']);

                caneDataExperiment_sync_file = strcat('S',numParticipante,'_caneDataExperiment');
                eval([caneDataExperiment_sync_file ' = caneDataExperiment_sync']);
                
                empaticaDataExperiment_sync_file_path = fullfile(carpetaSignal, empaticaDataExperiment_sync_file);
                caneDataExperiment_sync_file_path = fullfile(carpetaSignal, caneDataExperiment_sync_file);

                % .mat de la tabla con las características extraídas:
                empaticaFeatures_file = strcat('S',numParticipante,'_empaticaFeatures');
                eval([empaticaFeatures_file ' = empaticaFeatures']);

                caneFeatures_file = strcat('S',numParticipante,'_caneFeatures');
                eval([caneFeatures_file ' = caneFeatures']);

                empaticaFeatures_file_path = fullfile(carpetaFeatures, empaticaFeatures_file);
                caneFeatures_file_path = fullfile(carpetaFeatures, caneFeatures_file);

                % Verificamos si existe algún archivo con el mismo nombre
                % en la ruta seleccionada para sobreescribirlos
                if exist(strcat(protocolTime_datetime_file_path,'.mat'), 'file') || exist(strcat(empaticaDataExperiment_sync_file_path,'.mat'), 'file') || ...
                        exist(strcat(caneDataExperiment_sync_file_path,'.mat'), 'file') || exist(strcat(empaticaFeatures_file_path,'.mat'), 'file') || ...
                        exist(strcat(caneFeatures_file_path,'.mat'), 'file')
                    response = uiconfirm(app.UIFigure, ...
                        'Ya existe uno o varios archivos con el mismo nombre en la ruta especificada. ¿Deseas sobreescribir los datos?', ...
                        'Confirmar sobreescritura', ...
                        'Options', {'Sobrescribir', 'Cancelar'}, ...
                        'DefaultOption', 1, ...
                        'CancelOption', 2);
    
                    % Si el usuario elige 'Cancelar', no hacer nada
                    if strcmp(response, 'Cancelar')
                        return;
                    end
                end

                % Guardamos los archivos
                save(protocolTime_datetime_file_path, protocolTime_datetime_file);

                save(empaticaDataExperiment_sync_file_path, empaticaDataExperiment_sync_file);
                save(caneDataExperiment_sync_file_path, caneDataExperiment_sync_file);

                save(empaticaFeatures_file_path, empaticaFeatures_file);
                save(caneFeatures_file_path, caneFeatures_file);

                uialert(app.UIFigure, 'Los archivos se han guardado satisfactoriamente.', 'Éxito', 'Icon', 'success');

            catch ME  
                errorMsg = sprintf('Hubo un error al realizar guardar los archivos:\n\n%s\n\nEn: %s (línea %d)', ...
                                   ME.message, ...
                                   ME.stack(1).name, ...
                                   ME.stack(1).line);
                uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
                return;
            end
        end

        % Button pushed function: ExaminarFeaturesButton
        function ExaminarFeaturesButtonPushed(app, event)
            % Abrir el explorador de carpetas
            folderPath = uigetdir;
            % Verificar si el usuario seleccionó una carpeta o canceló la acción
            if folderPath ~= 0
                app.RutaGuardarFeatures.Value = folderPath;
            end
        end

        % Button pushed function: CalcularAEButton
        function CalcularAEButtonPushed(app, event)
            % Asignamos el vector de pesos de las preguntas.
            % La posición representa el ítem. (-1 = escala de puntuación invertida)
            pesosItems = ones(1, 20);
            itemsEscalaInvertida = [1, 2, 5, 8, 10, 11, 15, 16, 19, 20];
            pesosItems(itemsEscalaInvertida) = -1;
            
            experimento = {'relax1', 'musica', 'relax2', 'calculo'};
            
            for g = 1:length(experimento)
                groupSum = 0;
                group = experimento{g};
                for i = 1:20
                    itemName = sprintf('Item%d_%s', i, group);
                    itemValue = app.(itemName).Value;
                    if pesosItems(i) == 1
                        groupSum = groupSum + itemValue;                    
                    else
                        groupSum = groupSum + 3 - itemValue;
                    end
                end
                app.(sprintf('Field%s', group)).Value = groupSum;
            end

        end

        % Button pushed function: CalcularARButton
        function CalcularARButtonPushed(app, event)
            % Asignamos el vector de pesos de las preguntas.
            % La posición representa el ítem. (-1 = escala de puntuación invertida)
            pesosItems = ones(1, 20);
            itemsEscalaInvertida = [1, 6, 7, 10, 13, 16, 19];
            pesosItems(itemsEscalaInvertida) = -1;

            groupSum = 0;
            for i = 1:20
                itemName = sprintf('Item%d_calculo_2', i);
                itemValue = app.(itemName).Value;
                if pesosItems(i) == 1
                    groupSum = groupSum + itemValue;                    
                else
                    groupSum = groupSum + 3 - itemValue;
                end
            end
            app.FieldAR.Value = groupSum;
        end

        % Button pushed function: GuardarAEScore
        function GuardarAEScoreButtonPushed(app, event)
            if(app.Fieldrelax1.Value == 0) || (app.Fieldmusica.Value == 0)...
                    || (app.Fieldrelax2.Value == 0) || (app.Fieldcalculo.Value == 0)
                uialert(app.UIFigure, ['Las puntuaciones de AE deben ser positivas.'], 'Error', 'Icon', 'error');
                return
            end

            try
                % Abrir el explorador de carpetas
                rutaGuardar = uigetdir;
            catch
                uialert(app.UIFigure, 'Hubo un error al seleccionar el directorio.', 'Error', 'Icon', 'error');
                return;
            end

            try
                if rutaGuardar ~= 0
                    AE_Score = struct();
                    AE_Score.Relax1 = app.Fieldrelax1.Value;
                    AE_Score.Musica = app.Fieldmusica.Value;
                    AE_Score.Relax2 = app.Fieldrelax2.Value;
                    AE_Score.Calculo = app.Fieldcalculo.Value;
                    
                    % Obtenemos el número de participante
                    numParticipante = num2str(app.NumParticipante_2.Value);
                    fileName = strcat('S',numParticipante,'_AE_Score');
    
                    % Lo guardamos en la carpeta seleccionada
                    fullFileName = fullfile(rutaGuardar, fileName);

                    % Verificamos si el archivo ya existe
                    if exist(strcat(fullFileName,'.mat'), 'file')
                        response = uiconfirm(app.UIFigure, ...
                            ['Ya existe un archivo llamado ', fileName, ' en la ruta especificada. ¿Deseas sobreescribirlo?'], ...
                            'Confirmar sobreescritura', ...
                            'Options', {'Sobrescribir', 'Cancelar'}, ...
                            'DefaultOption', 1, ...
                            'CancelOption', 2);
                        
                        % Si el usuario elige 'Cancelar', no hacer nada
                        if strcmp(response, 'Cancelar')
                            return;
                        end
                    end
                    % Guardamos el archivo (si no existía o el usuario aceptó sobreescribir)
                    save(fullFileName, 'AE_Score');
                    uialert(app.UIFigure, 'El archivo se ha guardado satisfactoriamente.', 'Éxito', 'Icon', 'success');
       
                end                
            catch
                uialert(app.UIFigure, 'Hubo un error al guardar la puntuación A/E del participante.', 'Error', 'Icon', 'error');
                return;
            end    
        end

        % Button pushed function: GuardarARScore
        function GuardarARScoreButtonPushed(app, event)
            % Verificamos que el checkbox de 'Sexo' esté seleccionado y
            % sea 'Varón' o 'Mujer'.
            if ~app.Varon.Value && ~app.Mujer.Value
                uialert(app.UIFigure, 'Selecciona el Sexo del participante.', 'Advertencia', 'Icon', 'warning');
                return
            elseif app.Varon.Value && app.Mujer.Value
                uialert(app.UIFigure, 'Selecciona un único sexo del participante.', 'Advertencia', 'Icon', 'warning');
                return
            end
            
            % Verificamos que las respuestas generales estén contestadas
            if (~app.CafeNo.Value && ~app.CafeSi.Value) || (~app.DeporteNo.Value && ~app.DeporteSi.Value) || (~app.FumaNo.Value && ~app.FumaSi.Value) || (~app.HaFumadoNo.Value && ~app.HaFumadoSi.Value) ||  (~app.EnfermoNo.Value && ~app.EnfermoSi.Value)
                uialert(app.UIFigure, 'Rellena todas las casillas Sí/No de las preguntas generales.', 'Advertencia', 'Icon', 'warning');
                return
            end
            
            % Verificamos que la puntuación de del AR sea mayor que 0
            if(app.FieldAR.Value <= 0)
                uialert(app.UIFigure, 'Las puntuaciones de AR deben ser positivas.', 'Error', 'Icon', 'error');
                return
            end

            try
                % Abrir el explorador de carpetas
                rutaGuardar = uigetdir;
            catch
                uialert(app.UIFigure, 'Hubo un error al seleccionar el directorio.', 'Error', 'Icon', 'error');
                return;
            end

            try
                if rutaGuardar ~= 0
                    AR_Score = app.FieldAR.Value;
                    preguntasGenerales = struct();
                    preguntasGenerales.Subject = app.NumParticipante_3.Value;
                    preguntasGenerales.Edad = app.EdadParticipante.Value;
                    
                    if app.Mujer.Value
                        preguntasGenerales.Sexo = 'M';
                    else
                        preguntasGenerales.Sexo = 'V';
                    end
                    
                    if app.CafeSi.Value
                        preguntasGenerales.HasBebidoCafe = 1;
                    else
                        preguntasGenerales.HasBebidoCafe = 0;
                    end

                    if app.DeporteSi.Value
                        preguntasGenerales.HasHechoDeporte = 1;
                    else
                        preguntasGenerales.HasHechoDeporte = 0;
                    end      

                    if app.FumaSi.Value
                        preguntasGenerales.Fumador = 1;
                    else
                        preguntasGenerales.Fumador = 0;
                    end

                    if app.HaFumadoSi.Value
                        preguntasGenerales.HasFumadoRecientemente = 1;
                    else
                        preguntasGenerales.HasFumadoRecientemente = 0;
                    end

                    if app.EnfermoSi.Value
                        preguntasGenerales.MalestarFisico = 1;
                    else
                        preguntasGenerales.MalestarFisico = 0;
                    end
                    
                    % Obtenemos el número de participante
                    numParticipante = num2str(app.NumParticipante_3.Value);
                    fileName1 = strcat('S',numParticipante,'_AR_Score');
                    fileName2 = strcat('S',numParticipante,'_GenQuest');

                    % Lo guardamos en la carpeta seleccionada
                    fullFileName1 = fullfile(rutaGuardar, fileName1);
                    fullFileName2 = fullfile(rutaGuardar, fileName2);

                    % Verificamos si algunos de los archivos ya existe
                    if exist(strcat(fullFileName1,'.mat'), 'file')
                        response = uiconfirm(app.UIFigure, ...
                            ['Ya existe un archivo llamado ', fileName1, ' en la ruta especificada. ¿Deseas sobreescribirlo?'], ...
                            'Confirmar sobreescritura', ...
                            'Options', {'Sobrescribir', 'Cancelar'}, ...
                            'DefaultOption', 1, ...
                            'CancelOption', 2);
                        
                        % Si el usuario elige 'Cancelar', no hacer nada
                        if strcmp(response, 'Cancelar')
                            return;
                        end
                    end
                    if exist(strcat(fullFileName2,'.mat'), 'file')
                        response = uiconfirm(app.UIFigure, ...
                            ['Ya existe un archivo llamado ', fileName2, ' en la ruta especificada. ¿Deseas sobreescribirlo?'], ...
                            'Confirmar sobreescritura', ...
                            'Options', {'Sobrescribir', 'Cancelar'}, ...
                            'DefaultOption', 1, ...
                            'CancelOption', 2);
                        
                        if strcmp(response, 'Cancelar')
                            return;
                        end
                    end
      
                    save(fullFileName1, 'AR_Score');
                    save(fullFileName2, 'preguntasGenerales');
                    uialert(app.UIFigure, 'Los archivos se han guardado satisfactoriamente.', 'Éxito', 'Icon', 'success');
       
                end                
            catch
                uialert(app.UIFigure, 'Hubo un error al guardar la puntuación A/R del participante.', 'Error', 'Icon', 'error');
                return;
            end    
            

        end

        % Value changed function: Varon
        function VaronValueChanged(app, event)
            value = app.Varon.Value;
            if app.Mujer.Value == 1 && value
                app.Varon.Value = 1;
                app.Mujer.Value = 0;
            end
        end

        % Value changed function: Mujer
        function MujerValueChanged(app, event)
            value = app.Mujer.Value;
            if app.Varon.Value == 1 && value
                app.Mujer.Value = 1;
                app.Varon.Value = 0;
            end
        end

        % Value changed function: CafeSi
        function CafeSiValueChanged(app, event)
            value = app.CafeSi.Value;
            if app.CafeNo.Value == 1 && value
                app.CafeSi.Value = 1;
                app.CafeNo.Value = 0;
            end
        end

        % Value changed function: CafeNo
        function CafeNoValueChanged(app, event)
            value = app.CafeNo.Value;
            if app.CafeSi.Value == 1 && value
                app.CafeNo.Value = 1;
                app.CafeSi.Value = 0;
            end
        end

        % Value changed function: DeporteSi
        function DeporteSiValueChanged(app, event)
            value = app.DeporteSi.Value;
            if app.DeporteNo.Value == 1 && value
                app.DeporteSi.Value = 1;
                app.DeporteNo.Value = 0;
            end
        end

        % Value changed function: DeporteNo
        function DeporteNoValueChanged(app, event)
            value = app.DeporteNo.Value;
            if app.DeporteSi.Value == 1 && value
                app.DeporteNo.Value = 1;
                app.DeporteSi.Value = 0;
            end
        end

        % Value changed function: FumaSi
        function FumaSiValueChanged(app, event)
            value = app.FumaSi.Value;
            if app.FumaNo.Value == 1 && value
                app.FumaSi.Value = 1;
                app.FumaNo.Value = 0;
            end
        end

        % Value changed function: FumaNo
        function FumaNoValueChanged(app, event)
            value = app.FumaNo.Value;
            if app.FumaSi.Value == 1 && value
                app.FumaNo.Value = 1;
                app.FumaSi.Value = 0;
            end
        end

        % Value changed function: HaFumadoSi
        function HaFumadoSiValueChanged(app, event)
            value = app.HaFumadoSi.Value;
            if app.HaFumadoNo.Value == 1 && value
                app.HaFumadoSi.Value = 1;
                app.HaFumadoNo.Value = 0;
            end
        end

        % Value changed function: HaFumadoNo
        function HaFumadoNoValueChanged(app, event)
            value = app.HaFumadoNo.Value;
            if app.HaFumadoSi.Value == 1 && value
                app.HaFumadoNo.Value = 1;
                app.HaFumadoSi.Value = 0;
            end
        end

        % Value changed function: EnfermoSi
        function EnfermoSiValueChanged(app, event)
            value = app.EnfermoSi.Value;
            if app.EnfermoNo.Value == 1 && value
                app.EnfermoSi.Value = 1;
                app.EnfermoNo.Value = 0;
            end
        end

        % Value changed function: EnfermoNo
        function EnfermoNoValueChanged(app, event)
            value = app.EnfermoNo.Value;
            if app.EnfermoSi.Value == 1 && value
                app.EnfermoNo.Value = 1;
                app.EnfermoSi.Value = 0;
            end
        end

        % Value changed function: NumParticipante
        function NumParticipanteValueChanged(app, event)
            value = app.NumParticipante.Value;
            app.NumParticipante_2.Value = value;
            app.NumParticipante_3.Value = value;
        end

        % Value changed function: NumParticipante_2
        function NumParticipante_2ValueChanged(app, event)
            value = app.NumParticipante_2.Value;
            app.NumParticipante.Value = value;
            app.NumParticipante_3.Value = value;
        end

        % Value changed function: NumParticipante_3
        function NumParticipante_3ValueChanged(app, event)
            value = app.NumParticipante_3.Value;
            app.NumParticipante.Value = value;
            app.NumParticipante_2.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon_canesense.png');
            app.Experimentov10Label = uilabel(app.UIFigure);
            app.Experimentov10Label.Text = 'Experimento v1.0';
            app.RafaelVillalbaLabel = uilabel(app.UIFigure);
            app.RafaelVillalbaLabel.Text = 'Rafael Villalba';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '_______________';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioFinExperimentosTab = uitab(app.TabGroup);
            app.InicioFinExperimentosTab.Title = 'Inicio/Fin Experimentos';
            app.GridLayout = uigridlayout(app.InicioFinExperimentosTab);
            app.InicioLabel = uilabel(app.GridLayout);
            app.InicioLabel.Text = 'Inicio:';
            app.FinLabel = uilabel(app.GridLayout);
            app.FinLabel.Text = 'Fin:';
            app.Relax1Label = uilabel(app.GridLayout);
            app.Relax1Label.Text = 'Relax 1';
            app.EventosMsicaLabel = uilabel(app.GridLayout);
            app.EventosMsicaLabel.Text = 'Eventos Música';
            app.Relax2Label = uilabel(app.GridLayout);
            app.Relax2Label.Text = 'Relax 2';
            app.ClculoAritmticoLabel_2 = uilabel(app.GridLayout);
            app.ClculoAritmticoLabel_2.Text = 'Cálculo Aritmético';
            app.InicioRelax1 = uieditfield(app.GridLayout, 'text');
            app.InicioRelax1.Placeholder = 'HH:mm:ss.SS';
            app.InicioRelax1.Value = '15:59:38.00';
            app.FinRelax1 = uieditfield(app.GridLayout, 'text');
            app.FinRelax1.Placeholder = 'HH:mm:ss.SS';
            app.FinRelax1.Value = '16:00:43.00';
            app.InicioMusica = uieditfield(app.GridLayout, 'text');
            app.InicioMusica.Placeholder = 'HH:mm:ss.SS';
            app.InicioMusica.Value = '16:02:04.84';
            app.FinMusica = uieditfield(app.GridLayout, 'text');
            app.FinMusica.Placeholder = 'HH:mm:ss.SS';
            app.FinMusica.Value = '16:02:49.72';
            app.InicioRelax2 = uieditfield(app.GridLayout, 'text');
            app.InicioRelax2.Placeholder = 'HH:mm:ss.SS';
            app.InicioRelax2.Value = '16:00:48.25';
            app.FinRelax2 = uieditfield(app.GridLayout, 'text');
            app.FinRelax2.Placeholder = 'HH:mm:ss.SS';
            app.FinRelax2.Value = '16:01:57.72';
            app.InicioCalculo = uieditfield(app.GridLayout, 'text');
            app.InicioCalculo.Placeholder = 'HH:mm:ss.SS';
            app.InicioCalculo.Value = '16:02:52.53';
            app.FinCalculo = uieditfield(app.GridLayout, 'text');
            app.FinCalculo.Placeholder = 'HH:mm:ss.SS';
            app.FinCalculo.Value = '16:03:35.16';
            app.InicioFindelexperimentoLabel = uilabel(app.GridLayout);
            app.InicioFindelexperimentoLabel.Text = 'Inicio/Fin del experimento:';
            app.FechadelexperimentoLabel = uilabel(app.GridLayout);
            app.FechadelexperimentoLabel.Text = 'Fecha del experimento:';
            app.FechaExperimento = uidatepicker(app.GridLayout);
            app.FechaExperimento.Limits = [datetime([2025 4 8]) datetime([2030 12 31])];
            app.ParticipanteNLabel = uilabel(app.GridLayout);
            app.ParticipanteNLabel.Text = 'Participante Nº:';
            app.NumParticipante = uispinner(app.GridLayout);
            app.NumParticipante.Limits = [0 100];
            app.NumParticipante.ValueChangedFcn = createCallbackFcn(app, @NumParticipanteValueChanged, true);
            app.RutaGuardarFeaturesLabel = uilabel(app.GridLayout);
            app.RutaGuardarFeaturesLabel.Text = 'Ruta para guardar la tabla de características extraídas:';
            app.RutaGuardarFeatures = uieditfield(app.GridLayout, 'text');
            app.RutaGuardarFeatures.CharacterLimits = [0 250];
            app.RutaGuardarFeatures.Placeholder = 'Ej:  "C:\Users\Desktop"';
            app.ExaminarFeaturesButton = uibutton(app.GridLayout, 'push');
            app.ExaminarFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExaminarFeaturesButtonPushed, true);
            app.ExaminarFeaturesButton.Text = 'Examinar';
            app.GuardarFeaturesButton = uibutton(app.GridLayout, 'push');
            app.GuardarFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarFeaturesButtonPushed, true);
            app.GuardarFeaturesButton.Text = 'Guardar';
            app.TiempoVentanaLabel = uilabel(app.GridLayout);
            app.TiempoVentanaLabel.Text = 'Tiempo ventana (s):';
            app.TiempoOverlapLabel = uilabel(app.GridLayout);
            app.TiempoOverlapLabel.Text = 'Tiempo overlap (s):';
            app.TiempoVentana = uieditfield(app.GridLayout, 'numeric');
            app.TiempoVentana.Limits = [0 60];
            app.TiempoVentana.Value = 15;
            app.TiempoOverlap = uieditfield(app.GridLayout, 'numeric');
            app.TiempoOverlap.Limits = [0 60];
            app.TiempoOverlap.Value = 3.75;
            app.RutaGrabacionCaneSenseLabel = uilabel(app.GridLayout);
            app.RutaGrabacionCaneSenseLabel.Text = 'Grabación CaneSense:';
            app.RutaGrabacionEmpaticaLabel = uilabel(app.GridLayout);
            app.RutaGrabacionEmpaticaLabel.Text = 'Grabación Empatica:';
            app.RutaGrabacionCaneSense = uieditfield(app.GridLayout, 'text');
            app.RutaGrabacionCaneSense.CharacterLimits = [0 250];
            app.RutaGrabacionCaneSense.Placeholder = 'Ej:  "C:\Users\Desktop"';
            app.RutaGrabacionEmpatica = uieditfield(app.GridLayout, 'text');
            app.RutaGrabacionEmpatica.CharacterLimits = [0 250];
            app.RutaGrabacionEmpatica.Placeholder = 'Ej:  "C:\Users\Desktop"';
            app.EvaluacinCuestionarioAETab = uitab(app.TabGroup);
            app.EvaluacinCuestionarioAETab.Title = 'Evaluación Cuestionario A/E';
            app.GridLayout8 = uigridlayout(app.EvaluacinCuestionarioAETab);
            app.TabGroup2 = uitabgroup(app.GridLayout8);
            app.Relax1Tab = uitab(app.TabGroup2);
            app.Relax1Tab.Title = 'Relax 1';
            app.GridLayout2_4 = uigridlayout(app.Relax1Tab);
            app.Label1_relax1 = uilabel(app.GridLayout2_4);
            app.Label1_relax1.Text = '1.';
            app.Item1_relax1 = uispinner(app.GridLayout2_4);
            app.Item1_relax1.Limits = [0 3];
            app.Item14_relax1 = uispinner(app.GridLayout2_4);
            app.Item14_relax1.Limits = [0 3];
            app.Item11_relax1 = uispinner(app.GridLayout2_4);
            app.Item11_relax1.Limits = [0 3];
            app.Item2_relax1 = uispinner(app.GridLayout2_4);
            app.Item2_relax1.Limits = [0 3];
            app.Item5_relax1 = uispinner(app.GridLayout2_4);
            app.Item5_relax1.Limits = [0 3];
            app.Item12_relax1 = uispinner(app.GridLayout2_4);
            app.Item12_relax1.Limits = [0 3];
            app.Item3_relax1 = uispinner(app.GridLayout2_4);
            app.Item3_relax1.Limits = [0 3];
            app.Item4_relax1 = uispinner(app.GridLayout2_4);
            app.Item4_relax1.Limits = [0 3];
            app.Item13_relax1 = uispinner(app.GridLayout2_4);
            app.Item13_relax1.Limits = [0 3];
            app.Item15_relax1 = uispinner(app.GridLayout2_4);
            app.Item15_relax1.Limits = [0 3];
            app.Label11_relax1 = uilabel(app.GridLayout2_4);
            app.Label11_relax1.Text = '11.';
            app.Label12_relax1 = uilabel(app.GridLayout2_4);
            app.Label12_relax1.Text = '12.';
            app.Label13_relax1 = uilabel(app.GridLayout2_4);
            app.Label13_relax1.Text = '13.';
            app.Label14_relax1 = uilabel(app.GridLayout2_4);
            app.Label14_relax1.Text = '14.';
            app.Label15_relax1 = uilabel(app.GridLayout2_4);
            app.Label15_relax1.Text = '15.';
            app.Label2_relax1 = uilabel(app.GridLayout2_4);
            app.Label2_relax1.Text = '2.';
            app.Label3_relax1 = uilabel(app.GridLayout2_4);
            app.Label3_relax1.Text = '3.';
            app.Label4_relax1 = uilabel(app.GridLayout2_4);
            app.Label4_relax1.Text = '4.';
            app.Label5_relax1 = uilabel(app.GridLayout2_4);
            app.Label5_relax1.Text = '5.';
            app.Label6_relax1 = uilabel(app.GridLayout2_4);
            app.Label6_relax1.Text = '6.';
            app.Label8_relax1 = uilabel(app.GridLayout2_4);
            app.Label8_relax1.Text = '8.';
            app.Label16_relax1 = uilabel(app.GridLayout2_4);
            app.Label16_relax1.Text = '16.';
            app.Label17_relax1 = uilabel(app.GridLayout2_4);
            app.Label17_relax1.Text = '17.';
            app.Label7_relax1 = uilabel(app.GridLayout2_4);
            app.Label7_relax1.Text = '7.';
            app.Label9_relax1 = uilabel(app.GridLayout2_4);
            app.Label9_relax1.Text = '9.';
            app.Label10_relax1 = uilabel(app.GridLayout2_4);
            app.Label10_relax1.Text = '10';
            app.Label18_relax1 = uilabel(app.GridLayout2_4);
            app.Label18_relax1.Text = '18.';
            app.Label19_relax1 = uilabel(app.GridLayout2_4);
            app.Label19_relax1.Text = '19.';
            app.Label20_relax1 = uilabel(app.GridLayout2_4);
            app.Label20_relax1.Text = '20.';
            app.Item6_relax1 = uispinner(app.GridLayout2_4);
            app.Item6_relax1.Limits = [0 3];
            app.Item16_relax1 = uispinner(app.GridLayout2_4);
            app.Item16_relax1.Limits = [0 3];
            app.Item7_relax1 = uispinner(app.GridLayout2_4);
            app.Item7_relax1.Limits = [0 3];
            app.Item17_relax1 = uispinner(app.GridLayout2_4);
            app.Item17_relax1.Limits = [0 3];
            app.Item8_relax1 = uispinner(app.GridLayout2_4);
            app.Item8_relax1.Limits = [0 3];
            app.Item18_relax1 = uispinner(app.GridLayout2_4);
            app.Item18_relax1.Limits = [0 3];
            app.Item9_relax1 = uispinner(app.GridLayout2_4);
            app.Item9_relax1.Limits = [0 3];
            app.Item19_relax1 = uispinner(app.GridLayout2_4);
            app.Item19_relax1.Limits = [0 3];
            app.Item10_relax1 = uispinner(app.GridLayout2_4);
            app.Item10_relax1.Limits = [0 3];
            app.Item20_relax1 = uispinner(app.GridLayout2_4);
            app.Item20_relax1.Limits = [0 3];
            app.MsicaTab = uitab(app.TabGroup2);
            app.MsicaTab.Title = 'Música';
            app.GridLayout2_6 = uigridlayout(app.MsicaTab);
            app.Label1_3 = uilabel(app.GridLayout2_6);
            app.Label1_3.Text = '1.';
            app.Item1_musica = uispinner(app.GridLayout2_6);
            app.Item1_musica.Limits = [0 3];
            app.Item14_musica = uispinner(app.GridLayout2_6);
            app.Item14_musica.Limits = [0 3];
            app.Item11_musica = uispinner(app.GridLayout2_6);
            app.Item11_musica.Limits = [0 3];
            app.Item2_musica = uispinner(app.GridLayout2_6);
            app.Item2_musica.Limits = [0 3];
            app.Item5_musica = uispinner(app.GridLayout2_6);
            app.Item5_musica.Limits = [0 3];
            app.Item12_musica = uispinner(app.GridLayout2_6);
            app.Item12_musica.Limits = [0 3];
            app.Item3_musica = uispinner(app.GridLayout2_6);
            app.Item3_musica.Limits = [0 3];
            app.Item4_musica = uispinner(app.GridLayout2_6);
            app.Item4_musica.Limits = [0 3];
            app.Item13_musica = uispinner(app.GridLayout2_6);
            app.Item13_musica.Limits = [0 3];
            app.Item15_musica = uispinner(app.GridLayout2_6);
            app.Item15_musica.Limits = [0 3];
            app.Label11_3 = uilabel(app.GridLayout2_6);
            app.Label11_3.Text = '11.';
            app.Label12_3 = uilabel(app.GridLayout2_6);
            app.Label12_3.Text = '12.';
            app.Label13_3 = uilabel(app.GridLayout2_6);
            app.Label13_3.Text = '13.';
            app.Label14_3 = uilabel(app.GridLayout2_6);
            app.Label14_3.Text = '14.';
            app.Label15_3 = uilabel(app.GridLayout2_6);
            app.Label15_3.Text = '15.';
            app.Label2_3 = uilabel(app.GridLayout2_6);
            app.Label2_3.Text = '2.';
            app.Label3_3 = uilabel(app.GridLayout2_6);
            app.Label3_3.Text = '3.';
            app.Label4_3 = uilabel(app.GridLayout2_6);
            app.Label4_3.Text = '4.';
            app.Label5_3 = uilabel(app.GridLayout2_6);
            app.Label5_3.Text = '5.';
            app.Label6_3 = uilabel(app.GridLayout2_6);
            app.Label6_3.Text = '6.';
            app.Label8_3 = uilabel(app.GridLayout2_6);
            app.Label8_3.Text = '8.';
            app.Label16_3 = uilabel(app.GridLayout2_6);
            app.Label16_3.Text = '16.';
            app.Label17_3 = uilabel(app.GridLayout2_6);
            app.Label17_3.Text = '17.';
            app.Label7_3 = uilabel(app.GridLayout2_6);
            app.Label7_3.Text = '7.';
            app.Label9_3 = uilabel(app.GridLayout2_6);
            app.Label9_3.Text = '9.';
            app.Label10_3 = uilabel(app.GridLayout2_6);
            app.Label10_3.Text = '10';
            app.Label18_3 = uilabel(app.GridLayout2_6);
            app.Label18_3.Text = '18.';
            app.Label19_3 = uilabel(app.GridLayout2_6);
            app.Label19_3.Text = '19.';
            app.Label20_3 = uilabel(app.GridLayout2_6);
            app.Label20_3.Text = '20.';
            app.Item6_musica = uispinner(app.GridLayout2_6);
            app.Item6_musica.Limits = [0 3];
            app.Item16_musica = uispinner(app.GridLayout2_6);
            app.Item16_musica.Limits = [0 3];
            app.Item7_musica = uispinner(app.GridLayout2_6);
            app.Item7_musica.Limits = [0 3];
            app.Item17_musica = uispinner(app.GridLayout2_6);
            app.Item17_musica.Limits = [0 3];
            app.Item8_musica = uispinner(app.GridLayout2_6);
            app.Item8_musica.Limits = [0 3];
            app.Item18_musica = uispinner(app.GridLayout2_6);
            app.Item18_musica.Limits = [0 3];
            app.Item9_musica = uispinner(app.GridLayout2_6);
            app.Item9_musica.Limits = [0 3];
            app.Item19_musica = uispinner(app.GridLayout2_6);
            app.Item19_musica.Limits = [0 3];
            app.Item10_musica = uispinner(app.GridLayout2_6);
            app.Item10_musica.Limits = [0 3];
            app.Item20_musica = uispinner(app.GridLayout2_6);
            app.Item20_musica.Limits = [0 3];
            app.Relax2Tab = uitab(app.TabGroup2);
            app.Relax2Tab.Title = 'Relax 2';
            app.GridLayout2_7 = uigridlayout(app.Relax2Tab);
            app.Label1_relax2 = uilabel(app.GridLayout2_7);
            app.Label1_relax2.Text = '1.';
            app.Item1_relax2 = uispinner(app.GridLayout2_7);
            app.Item1_relax2.Limits = [0 3];
            app.Item14_relax2 = uispinner(app.GridLayout2_7);
            app.Item14_relax2.Limits = [0 3];
            app.Item11_relax2 = uispinner(app.GridLayout2_7);
            app.Item11_relax2.Limits = [0 3];
            app.Item2_relax2 = uispinner(app.GridLayout2_7);
            app.Item2_relax2.Limits = [0 3];
            app.Item5_relax2 = uispinner(app.GridLayout2_7);
            app.Item5_relax2.Limits = [0 3];
            app.Item12_relax2 = uispinner(app.GridLayout2_7);
            app.Item12_relax2.Limits = [0 3];
            app.Item3_relax2 = uispinner(app.GridLayout2_7);
            app.Item3_relax2.Limits = [0 3];
            app.Item4_relax2 = uispinner(app.GridLayout2_7);
            app.Item4_relax2.Limits = [0 3];
            app.Item13_relax2 = uispinner(app.GridLayout2_7);
            app.Item13_relax2.Limits = [0 3];
            app.Item15_relax2 = uispinner(app.GridLayout2_7);
            app.Item15_relax2.Limits = [0 3];
            app.Label11_relax2 = uilabel(app.GridLayout2_7);
            app.Label11_relax2.Text = '11.';
            app.Label12_relax2 = uilabel(app.GridLayout2_7);
            app.Label12_relax2.Text = '12.';
            app.Label13_relax2 = uilabel(app.GridLayout2_7);
            app.Label13_relax2.Text = '13.';
            app.Label14_relax2 = uilabel(app.GridLayout2_7);
            app.Label14_relax2.Text = '14.';
            app.Label15_relax2 = uilabel(app.GridLayout2_7);
            app.Label15_relax2.Text = '15.';
            app.Label2_relax2 = uilabel(app.GridLayout2_7);
            app.Label2_relax2.Text = '2.';
            app.Label3_relax2 = uilabel(app.GridLayout2_7);
            app.Label3_relax2.Text = '3.';
            app.Label4_relax2 = uilabel(app.GridLayout2_7);
            app.Label4_relax2.Text = '4.';
            app.Label5_relax2 = uilabel(app.GridLayout2_7);
            app.Label5_relax2.Text = '5.';
            app.Label6_relax2 = uilabel(app.GridLayout2_7);
            app.Label6_relax2.Text = '6.';
            app.Label8_relax2 = uilabel(app.GridLayout2_7);
            app.Label8_relax2.Text = '8.';
            app.Label16_relax2 = uilabel(app.GridLayout2_7);
            app.Label16_relax2.Text = '16.';
            app.Label17_4 = uilabel(app.GridLayout2_7);
            app.Label17_4.Text = '17.';
            app.Label7_4 = uilabel(app.GridLayout2_7);
            app.Label7_4.Text = '7.';
            app.Label9_4 = uilabel(app.GridLayout2_7);
            app.Label9_4.Text = '9.';
            app.Label10_4 = uilabel(app.GridLayout2_7);
            app.Label10_4.Text = '10';
            app.Label18_4 = uilabel(app.GridLayout2_7);
            app.Label18_4.Text = '18.';
            app.Label19_4 = uilabel(app.GridLayout2_7);
            app.Label19_4.Text = '19.';
            app.Label20_4 = uilabel(app.GridLayout2_7);
            app.Label20_4.Text = '20.';
            app.Item6_relax2 = uispinner(app.GridLayout2_7);
            app.Item6_relax2.Limits = [0 3];
            app.Item16_relax2 = uispinner(app.GridLayout2_7);
            app.Item16_relax2.Limits = [0 3];
            app.Item7_relax2 = uispinner(app.GridLayout2_7);
            app.Item7_relax2.Limits = [0 3];
            app.Item17_relax2 = uispinner(app.GridLayout2_7);
            app.Item17_relax2.Limits = [0 3];
            app.Item8_relax2 = uispinner(app.GridLayout2_7);
            app.Item8_relax2.Limits = [0 3];
            app.Item18_relax2 = uispinner(app.GridLayout2_7);
            app.Item18_relax2.Limits = [0 3];
            app.Item9_relax2 = uispinner(app.GridLayout2_7);
            app.Item9_relax2.Limits = [0 3];
            app.Item19_relax2 = uispinner(app.GridLayout2_7);
            app.Item19_relax2.Limits = [0 3];
            app.Item10_relax2 = uispinner(app.GridLayout2_7);
            app.Item10_relax2.Limits = [0 3];
            app.Item20_relax2 = uispinner(app.GridLayout2_7);
            app.Item20_relax2.Limits = [0 3];
            app.ClculoTab_2 = uitab(app.TabGroup2);
            app.ClculoTab_2.Title = 'Cálculo';
            app.GridLayout2_5 = uigridlayout(app.ClculoTab_2);
            app.Label1_2 = uilabel(app.GridLayout2_5);
            app.Label1_2.Text = '1.';
            app.Item1_calculo = uispinner(app.GridLayout2_5);
            app.Item1_calculo.Limits = [0 3];
            app.Item14_calculo = uispinner(app.GridLayout2_5);
            app.Item14_calculo.Limits = [0 3];
            app.Item11_calculo = uispinner(app.GridLayout2_5);
            app.Item11_calculo.Limits = [0 3];
            app.Item2_calculo = uispinner(app.GridLayout2_5);
            app.Item2_calculo.Limits = [0 3];
            app.Item5_calculo = uispinner(app.GridLayout2_5);
            app.Item5_calculo.Limits = [0 3];
            app.Item12_calculo = uispinner(app.GridLayout2_5);
            app.Item12_calculo.Limits = [0 3];
            app.Item3_calculo = uispinner(app.GridLayout2_5);
            app.Item3_calculo.Limits = [0 3];
            app.Item4_calculo = uispinner(app.GridLayout2_5);
            app.Item4_calculo.Limits = [0 3];
            app.Item13_calculo = uispinner(app.GridLayout2_5);
            app.Item13_calculo.Limits = [0 3];
            app.Item15_calculo = uispinner(app.GridLayout2_5);
            app.Item15_calculo.Limits = [0 3];
            app.Label11_2 = uilabel(app.GridLayout2_5);
            app.Label11_2.Text = '11.';
            app.Label12_2 = uilabel(app.GridLayout2_5);
            app.Label12_2.Text = '12.';
            app.Label13_2 = uilabel(app.GridLayout2_5);
            app.Label13_2.Text = '13.';
            app.Label14_2 = uilabel(app.GridLayout2_5);
            app.Label14_2.Text = '14.';
            app.Label15_2 = uilabel(app.GridLayout2_5);
            app.Label15_2.Text = '15.';
            app.Label2_2 = uilabel(app.GridLayout2_5);
            app.Label2_2.Text = '2.';
            app.Label3_2 = uilabel(app.GridLayout2_5);
            app.Label3_2.Text = '3.';
            app.Label4_2 = uilabel(app.GridLayout2_5);
            app.Label4_2.Text = '4.';
            app.Label5_2 = uilabel(app.GridLayout2_5);
            app.Label5_2.Text = '5.';
            app.Label6_2 = uilabel(app.GridLayout2_5);
            app.Label6_2.Text = '6.';
            app.Label8_2 = uilabel(app.GridLayout2_5);
            app.Label8_2.Text = '8.';
            app.Label16_2 = uilabel(app.GridLayout2_5);
            app.Label16_2.Text = '16.';
            app.Label17_2 = uilabel(app.GridLayout2_5);
            app.Label17_2.Text = '17.';
            app.Label7_2 = uilabel(app.GridLayout2_5);
            app.Label7_2.Text = '7.';
            app.Label9_2 = uilabel(app.GridLayout2_5);
            app.Label9_2.Text = '9.';
            app.Label10_2 = uilabel(app.GridLayout2_5);
            app.Label10_2.Text = '10';
            app.Label18_2 = uilabel(app.GridLayout2_5);
            app.Label18_2.Text = '18.';
            app.Label19_2 = uilabel(app.GridLayout2_5);
            app.Label19_2.Text = '19.';
            app.Label20_2 = uilabel(app.GridLayout2_5);
            app.Label20_2.Text = '20.';
            app.Item6_calculo = uispinner(app.GridLayout2_5);
            app.Item6_calculo.Limits = [0 3];
            app.Item16_calculo = uispinner(app.GridLayout2_5);
            app.Item16_calculo.Limits = [0 3];
            app.Item7_calculo = uispinner(app.GridLayout2_5);
            app.Item7_calculo.Limits = [0 3];
            app.Item17_calculo = uispinner(app.GridLayout2_5);
            app.Item17_calculo.Limits = [0 3];
            app.Item8_calculo = uispinner(app.GridLayout2_5);
            app.Item8_calculo.Limits = [0 3];
            app.Item18_calculo = uispinner(app.GridLayout2_5);
            app.Item18_calculo.Limits = [0 3];
            app.Item9_calculo = uispinner(app.GridLayout2_5);
            app.Item9_calculo.Limits = [0 3];
            app.Item19_calculo = uispinner(app.GridLayout2_5);
            app.Item19_calculo.Limits = [0 3];
            app.Item10_calculo = uispinner(app.GridLayout2_5);
            app.Item10_calculo.Limits = [0 3];
            app.Item20_calculo = uispinner(app.GridLayout2_5);
            app.Item20_calculo.Limits = [0 3];
            app.ParticipanteNLabel_2 = uilabel(app.GridLayout8);
            app.ParticipanteNLabel_2.Text = 'Participante Nº:';
            app.GuardarAEScore = uibutton(app.GridLayout8, 'push');
            app.GuardarAEScore.ButtonPushedFcn = createCallbackFcn(app, @GuardarAEScoreButtonPushed, true);
            app.GuardarAEScore.Text = 'Guardar';
            app.CalcularAEButton = uibutton(app.GridLayout8, 'push');
            app.CalcularAEButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularAEButtonPushed, true);
            app.CalcularAEButton.Text = 'Calcular';
            app.NumParticipante_2 = uispinner(app.GridLayout8);
            app.NumParticipante_2.Limits = [0 100];
            app.NumParticipante_2.ValueChangedFcn = createCallbackFcn(app, @NumParticipante_2ValueChanged, true);
            app.Relax1Label_2 = uilabel(app.GridLayout8);
            app.Relax1Label_2.Text = 'Relax 1';
            app.ExperimentoLabel = uilabel(app.GridLayout8);
            app.ExperimentoLabel.Text = 'Experimento';
            app.STAIAEScoreLabel = uilabel(app.GridLayout8);
            app.STAIAEScoreLabel.Text = 'STAI A/E Score';
            app.MsicaLabel = uilabel(app.GridLayout8);
            app.MsicaLabel.Text = 'Música';
            app.Relax2Label_2 = uilabel(app.GridLayout8);
            app.Relax2Label_2.Text = 'Relax 2';
            app.ClculoLabel = uilabel(app.GridLayout8);
            app.ClculoLabel.Text = 'Cálculo';
            app.Fieldrelax1 = uieditfield(app.GridLayout8, 'numeric');
            app.Fieldrelax1.Editable = 'off';
            app.Fieldmusica = uieditfield(app.GridLayout8, 'numeric');
            app.Fieldmusica.Editable = 'off';
            app.Fieldrelax2 = uieditfield(app.GridLayout8, 'numeric');
            app.Fieldrelax2.Editable = 'off';
            app.Fieldcalculo = uieditfield(app.GridLayout8, 'numeric');
            app.Fieldcalculo.Editable = 'off';
            app.Label_2 = uilabel(app.GridLayout8);
            app.Label_2.Text = '';
            app.EvaluacinCuestionarioARTab = uitab(app.TabGroup);
            app.EvaluacinCuestionarioARTab.Title = 'Evaluación Cuestionario A/R';
            app.GridLayout8_2 = uigridlayout(app.EvaluacinCuestionarioARTab);
            app.TabGroup2_2 = uitabgroup(app.GridLayout8_2);
            app.ARTab = uitab(app.TabGroup2_2);
            app.ARTab.Title = 'A/R';
            app.GridLayout2_11 = uigridlayout(app.ARTab);
            app.Label1_5 = uilabel(app.GridLayout2_11);
            app.Label1_5.Text = '21.';
            app.Item1_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item1_calculo_2.Limits = [0 3];
            app.Item14_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item14_calculo_2.Limits = [0 3];
            app.Item11_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item11_calculo_2.Limits = [0 3];
            app.Item2_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item2_calculo_2.Limits = [0 3];
            app.Item5_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item5_calculo_2.Limits = [0 3];
            app.Item12_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item12_calculo_2.Limits = [0 3];
            app.Item3_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item3_calculo_2.Limits = [0 3];
            app.Item4_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item4_calculo_2.Limits = [0 3];
            app.Item13_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item13_calculo_2.Limits = [0 3];
            app.Item15_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item15_calculo_2.Limits = [0 3];
            app.Label11_5 = uilabel(app.GridLayout2_11);
            app.Label11_5.Text = '31.';
            app.Label12_5 = uilabel(app.GridLayout2_11);
            app.Label12_5.Text = '32.';
            app.Label13_5 = uilabel(app.GridLayout2_11);
            app.Label13_5.Text = '33.';
            app.Label14_5 = uilabel(app.GridLayout2_11);
            app.Label14_5.Text = '34.';
            app.Label15_5 = uilabel(app.GridLayout2_11);
            app.Label15_5.Text = '35.';
            app.Label2_5 = uilabel(app.GridLayout2_11);
            app.Label2_5.Text = '22.';
            app.Label3_5 = uilabel(app.GridLayout2_11);
            app.Label3_5.Text = '23.';
            app.Label4_5 = uilabel(app.GridLayout2_11);
            app.Label4_5.Text = '24.';
            app.Label5_5 = uilabel(app.GridLayout2_11);
            app.Label5_5.Text = '25.';
            app.Label6_5 = uilabel(app.GridLayout2_11);
            app.Label6_5.Text = '26.';
            app.Label8_5 = uilabel(app.GridLayout2_11);
            app.Label8_5.Text = '28.';
            app.Label16_5 = uilabel(app.GridLayout2_11);
            app.Label16_5.Text = '36.';
            app.Label17_7 = uilabel(app.GridLayout2_11);
            app.Label17_7.Text = '37.';
            app.Label7_7 = uilabel(app.GridLayout2_11);
            app.Label7_7.Text = '27.';
            app.Label9_7 = uilabel(app.GridLayout2_11);
            app.Label9_7.Text = '29.';
            app.Label10_7 = uilabel(app.GridLayout2_11);
            app.Label10_7.Text = '30';
            app.Label18_7 = uilabel(app.GridLayout2_11);
            app.Label18_7.Text = '38.';
            app.Label19_7 = uilabel(app.GridLayout2_11);
            app.Label19_7.Text = '39.';
            app.Label20_7 = uilabel(app.GridLayout2_11);
            app.Label20_7.Text = '40.';
            app.Item6_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item6_calculo_2.Limits = [0 3];
            app.Item16_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item16_calculo_2.Limits = [0 3];
            app.Item7_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item7_calculo_2.Limits = [0 3];
            app.Item17_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item17_calculo_2.Limits = [0 3];
            app.Item8_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item8_calculo_2.Limits = [0 3];
            app.Item18_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item18_calculo_2.Limits = [0 3];
            app.Item9_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item9_calculo_2.Limits = [0 3];
            app.Item19_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item19_calculo_2.Limits = [0 3];
            app.Item10_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item10_calculo_2.Limits = [0 3];
            app.Item20_calculo_2 = uispinner(app.GridLayout2_11);
            app.Item20_calculo_2.Limits = [0 3];
            app.PreguntasGeneralesTab = uitab(app.TabGroup2_2);
            app.PreguntasGeneralesTab.Title = 'Preguntas Generales';
            app.GridLayout12 = uigridlayout(app.PreguntasGeneralesTab);
            app.EdadLabel = uilabel(app.GridLayout12);
            app.EdadLabel.Text = 'Edad:';
            app.EdadParticipante = uispinner(app.GridLayout12);
            app.EdadParticipante.Limits = [18 100];
            app.EdadParticipante.Value = 18;
            app.Mujer = uicheckbox(app.GridLayout12);
            app.Mujer.ValueChangedFcn = createCallbackFcn(app, @MujerValueChanged, true);
            app.Mujer.Text = 'Mujer';
            app.SexoLabel = uilabel(app.GridLayout12);
            app.SexoLabel.Text = 'Sexo:';
            app.Varon = uicheckbox(app.GridLayout12);
            app.Varon.ValueChangedFcn = createCallbackFcn(app, @VaronValueChanged, true);
            app.Varon.Text = 'Varón';
            app.CafeSi = uicheckbox(app.GridLayout12);
            app.CafeSi.ValueChangedFcn = createCallbackFcn(app, @CafeSiValueChanged, true);
            app.CafeSi.Text = 'Sí';
            app.CafeNo = uicheckbox(app.GridLayout12);
            app.CafeNo.ValueChangedFcn = createCallbackFcn(app, @CafeNoValueChanged, true);
            app.CafeNo.Text = 'No';
            app.DeporteSi = uicheckbox(app.GridLayout12);
            app.DeporteSi.ValueChangedFcn = createCallbackFcn(app, @DeporteSiValueChanged, true);
            app.DeporteSi.Text = 'Sí';
            app.DeporteNo = uicheckbox(app.GridLayout12);
            app.DeporteNo.ValueChangedFcn = createCallbackFcn(app, @DeporteNoValueChanged, true);
            app.DeporteNo.Text = 'No';
            app.FumaSi = uicheckbox(app.GridLayout12);
            app.FumaSi.ValueChangedFcn = createCallbackFcn(app, @FumaSiValueChanged, true);
            app.FumaSi.Text = 'Sí';
            app.FumaNo = uicheckbox(app.GridLayout12);
            app.FumaNo.ValueChangedFcn = createCallbackFcn(app, @FumaNoValueChanged, true);
            app.FumaNo.Text = 'No';
            app.HaFumadoSi = uicheckbox(app.GridLayout12);
            app.HaFumadoSi.ValueChangedFcn = createCallbackFcn(app, @HaFumadoSiValueChanged, true);
            app.HaFumadoSi.Text = 'Sí';
            app.HaFumadoNo = uicheckbox(app.GridLayout12);
            app.HaFumadoNo.ValueChangedFcn = createCallbackFcn(app, @HaFumadoNoValueChanged, true);
            app.HaFumadoNo.Text = 'No';
            app.Hasbebidocafenlasltimas6horasLabel = uilabel(app.GridLayout12);
            app.Hasbebidocafenlasltimas6horasLabel.Text = '¿Has bebido café en las últimas 6 horas?';
            app.HashechodeportedealtaintensidadhoyLabel = uilabel(app.GridLayout12);
            app.HashechodeportedealtaintensidadhoyLabel.Text = '¿Has hecho deporte de alta intensidad hoy?';
            app.EresfumadorLabel = uilabel(app.GridLayout12);
            app.EresfumadorLabel.Text = '¿Eres fumador?';
            app.HasfumadoenlaltimahoraantesdelexpLabel = uilabel(app.GridLayout12);
            app.HasfumadoenlaltimahoraantesdelexpLabel.Text = '¿Has fumado en la última hora antes del exp.?';
            app.TesientesconmalcuerpooenfermoLabel = uilabel(app.GridLayout12);
            app.TesientesconmalcuerpooenfermoLabel.Text = '¿Te sientes con mal cuerpo o enfermo?';
            app.EnfermoSi = uicheckbox(app.GridLayout12);
            app.EnfermoSi.ValueChangedFcn = createCallbackFcn(app, @EnfermoSiValueChanged, true);
            app.EnfermoSi.Text = 'Sí';
            app.EnfermoNo = uicheckbox(app.GridLayout12);
            app.EnfermoNo.ValueChangedFcn = createCallbackFcn(app, @EnfermoNoValueChanged, true);
            app.EnfermoNo.Text = 'No';
            app.ParticipanteNLabel_3 = uilabel(app.GridLayout8_2);
            app.ParticipanteNLabel_3.Text = 'Participante Nº:';
            app.GuardarARScore = uibutton(app.GridLayout8_2, 'push');
            app.GuardarARScore.ButtonPushedFcn = createCallbackFcn(app, @GuardarARScoreButtonPushed, true);
            app.GuardarARScore.Text = 'Guardar';
            app.CalcularARButton = uibutton(app.GridLayout8_2, 'push');
            app.CalcularARButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularARButtonPushed, true);
            app.CalcularARButton.Text = 'Calcular';
            app.NumParticipante_3 = uispinner(app.GridLayout8_2);
            app.NumParticipante_3.Limits = [0 100];
            app.NumParticipante_3.ValueChangedFcn = createCallbackFcn(app, @NumParticipante_3ValueChanged, true);
            app.STAIARScoreLabel = uilabel(app.GridLayout8_2);
            app.STAIARScoreLabel.Text = 'STAI A/R Score';
            app.FieldAR = uieditfield(app.GridLayout8_2, 'numeric');
            app.FieldAR.Editable = 'off';
            app.Label_3 = uilabel(app.GridLayout8_2);
            app.Label_3.Text = '';
            app.Experimentov10Label_2 = uilabel(app.UIFigure);
            app.Experimentov10Label_2.Text = 'Experimento v1.0';
            app.RafaelVillalbaLabel_2 = uilabel(app.UIFigure);
            app.RafaelVillalbaLabel_2.Text = 'Rafael Villalba';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.Enable = 'off';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icon_canesense.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExperimentoProtocolo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end